classdef ResilienceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout3                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        CheckalocationforDACproximityPanel  matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        CheckLocationButton             matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        latoLabel                       matlab.ui.control.Label
        Edit_lat                        matlab.ui.control.EditField
        lonoLabel                       matlab.ui.control.Label
        radiusmiLabel                   matlab.ui.control.Label
        Edit_rad                        matlab.ui.control.EditField
        Edit_lon                        matlab.ui.control.EditField
        FilterVegetationIndexPanel      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        UpdateNDVIButton                matlab.ui.control.Button
        DropDown_NatHazLvlNDVI          matlab.ui.control.DropDown
        DropDown_NatHazNDVI             matlab.ui.control.DropDown
        NaturalHazardRiskNDVIButton     matlab.ui.control.StateButton
        PointsourceFiltersPanel         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        DropDown_PopGTLT                matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        peopleLabel                     matlab.ui.control.Label
        MTCO_2eaLabel                   matlab.ui.control.Label
        milesLabel                      matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Edit_Population                 matlab.ui.control.EditField
        Edit_Emissions                  matlab.ui.control.EditField
        Edit_DistInj                    matlab.ui.control.EditField
        DropDown_NatHazLvlPntSrc        matlab.ui.control.DropDown
        DropDown_NatHazPntSrc           matlab.ui.control.DropDown
        UpdatepointsourcesButton        matlab.ui.control.Button
        PopulationButton                matlab.ui.control.StateButton
        EmissionsButton                 matlab.ui.control.StateButton
        NaturalHazardRiskPntSrcButton   matlab.ui.control.StateButton
        DistancetoInjectionButton       matlab.ui.control.StateButton
        DataPanel                       matlab.ui.container.Panel
        DataTree                        matlab.ui.container.CheckBoxTree
        PointSourcesNode                matlab.ui.container.TreeNode
        PowerPlantsNode                 matlab.ui.container.TreeNode
        CementPlantsNode                matlab.ui.container.TreeNode
        EthanolPlantsNode               matlab.ui.container.TreeNode
        IronandSteelPlantsNode          matlab.ui.container.TreeNode
        CCSInfrastructureNode           matlab.ui.container.TreeNode
        PipelinesNode                   matlab.ui.container.TreeNode
        OperationalPipelinesNode        matlab.ui.container.TreeNode
        PlannedPipelinesNode            matlab.ui.container.TreeNode
        InjectionsitesNode              matlab.ui.container.TreeNode
        GeologicalreservoirsNode        matlab.ui.container.TreeNode
        NaturalHazardsNode              matlab.ui.container.TreeNode
        PresentNode                     matlab.ui.container.TreeNode
        EarthquakeNode                  matlab.ui.container.TreeNode
        DroughtNode                     matlab.ui.container.TreeNode
        HurricaneNode                   matlab.ui.container.TreeNode
        RiverineFloodingNode            matlab.ui.container.TreeNode
        StrongWindNode                  matlab.ui.container.TreeNode
        WildfireNode                    matlab.ui.container.TreeNode
        Projected2050Node               matlab.ui.container.TreeNode
        MaxSummerDailyAvgTemperatureFNode  matlab.ui.container.TreeNode
        RCP85Node_2                     matlab.ui.container.TreeNode
        ConsecutiveDayswithoutPrecipitationNode  matlab.ui.container.TreeNode
        RCP45Node                       matlab.ui.container.TreeNode
        RCP85Node                       matlab.ui.container.TreeNode
        TotalAnnualPrecipitationinNode  matlab.ui.container.TreeNode
        RCP45Node_2                     matlab.ui.container.TreeNode
        RCP85Node_3                     matlab.ui.container.TreeNode
        GridCarbonIntensitylbsCO2MWhNode  matlab.ui.container.TreeNode
        current2021Node                 matlab.ui.container.TreeNode
        projected2050Node               matlab.ui.container.TreeNode
        NDVINode                        matlab.ui.container.TreeNode
        SocialVulnerabilityNode         matlab.ui.container.TreeNode
        CommunityResilienceNode         matlab.ui.container.TreeNode
        PopulationNode                  matlab.ui.container.TreeNode
        InterstatesNode                 matlab.ui.container.TreeNode
        DisadvantagedCommunitiesNode    matlab.ui.container.TreeNode
    end


    properties (Access = private)
        ax  % Description
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            clc;close all

            % initialize map
            gx = geoaxes(app.Panel, 'Basemap', 'darkwater', 'NextPlot', 'add');
            gx.Title.FontName = 'Helvetica';
            gx.Title.String = 'MCSC Interactive Geospatial Mapping Tool'; % display label, come up with better name
            gx.Title.FontSize = 18;
            gx.Subtitle.String = sprintf('An interactive map to support business decisions regarding CCS and resiliency retrofitting');
            gx.Subtitle.FontName = 'Helvetica';
            gx.Subtitle.FontSize = 16;
            gx.FontSize = 16;
            gx.Subtitle.FontAngle = 'italic';
            gx.Scalebar.Visible = 'on'; % display scale bar
            gx.Grid = "off"; % no grid (may be distracting)
            % add option to turn grid on
            gx.LatitudeLabel.FontName = 'Helvetica';
            gx.LatitudeLabel.FontSize = 16;
            gx.LongitudeLabel.FontName = 'Helvetica';
            gx.LongitudeLabel.FontSize = 16;
            tlbr = axtoolbar(gx, {'export', 'datacursor', 'stepzoomin', 'stepzoomout', 'restoreview'}); % add differfent options to map toolbar
            addToolbarMapButton(tlbr, "basemap"); % allow user to choose different basemaps for personalized visualization
            geolimits(gx, [10 70], [-180 -30]); % map shows entirety of the USA
            hold(gx,'on')
            legend(gx,'-dynamiclegend','Tag','legend')

            % load data into nodes
            app = loadData(app);

        end

        % Callback function: DataTree
        function checkchange(app, event)
            checkedNodes = app.DataTree.CheckedNodes;
            if ismember(event.Source.SelectedNodes,event.Source.CheckedNodes) % the user 'turned on' a data layer => plot it
                for i = 1:length(event.Source.CheckedNodes)
                    if ~isempty(event.Source.CheckedNodes(i).NodeData) % if the checked node has a populated NodeData field
                        if  length(app.ax.Children)<1
                            switch event.Source.CheckedNodes(i).NodeData.Shape(1).Geometry % select the first shape file to determine the type of shape
                                case "point"
                                    pointLayer(app.ax, event.Source.CheckedNodes(i))
                                case "line"
                                    lineLayer(app.ax, event.Source.CheckedNodes(i))
                                case "polygon"
                                    polyLayer(app.ax, event.Source.CheckedNodes(i))
                            end
                        else
                            % MATLAB finds another child of app.ax that is
                            % class 'map.graphics.primitive.Data' without a
                            % tag, so this is a fix
                            for k = 1:length(app.ax.Children); Tag_array{k} = app.ax.Children(k).Tag;end

                            if ~ismember(event.Source.CheckedNodes(i).Tag,Tag_array) % if the checked node is not already plotted
                                % (if Children is not defined because there are no layers on the map, this statement
                                % generates an error even if it is the second condition of and OR with the first if branch being hte first condition
                                switch event.Source.CheckedNodes(i).NodeData.Shape(1).Geometry % select the first shape file to determine the type of shape
                                    case "point"
                                        pointLayer(app.ax, event.Source.CheckedNodes(i))
                                    case "line"
                                        lineLayer(app.ax, event.Source.CheckedNodes(i))
                                    case "polygon"
                                        polyLayer(app.ax, event.Source.CheckedNodes(i))
                                end
                            end
                        end
                    end
                end
            else % the user de-selected a node => remove the plot
                objs = get(app.ax, 'Children');
                if ~isempty(objs)
                    % get ids of leaf nodes that are no longer checked
                    ids_nodes_2_del = ~ismember(event.PreviousLeafCheckedNodes,event.LeafCheckedNodes);
                    % get ids of the plotted objects to be deleted
                    objs_cell = num2cell(objs);
                    obj_tag_cell = cellfun(@(x) x.Tag,objs_cell,'UniformOutput',false);
                    ids_objs_2_del   = ismember(obj_tag_cell,{event.PreviousLeafCheckedNodes(ids_nodes_2_del).Text});
                    delete(objs(ids_objs_2_del))
                end
            end

        end

        % Double-clicked callback: DataTree
        function DataTreeDoubleClicked(app, event)
            get_source(event.Source.SelectedNodes.Text);
        end

        % Size changed function: Panel
        function resetMapSize(app, event)
            if isempty(app.ax)
                if ~isempty(app.Panel.Children)
                    for i = 1:length(app.Panel.Children)
                        if isa(app.Panel.Children(i),'matlab.graphics.axis.GeographicAxes')
                            app.ax = app.Panel.Children(i);
                        end
                    end
                end
            else
                app.ax.Units = 'normalized';
                app.ax.Position = [0.1 0.1 0.8 0.8];
            end

        end

        % Button pushed function: UpdatepointsourcesButton
        function filter_pt_src(app, event)
            inds_pt_src = find(ismember(app.DataTree.CheckedNodes, app.PointSourcesNode.Children));
            if ~isempty(inds_pt_src)% if at least one point source is checked
                for j = 1:length(inds_pt_src)
                    PntSrc_name = app.DataTree.CheckedNodes(inds_pt_src(j)).Text;

                    if app.DistancetoInjectionButton.Value
                        % distance from injection site filter
                        ids_2_plot_dist = filter_by_distance(PntSrc_name,str2double(app.Edit_DistInj.Value));
                    else
                        ids_2_plot_dist = true(size(app.DataTree.CheckedNodes(inds_pt_src(j)).NodeData,1),1);
                    end

                    if app.NaturalHazardRiskPntSrcButton.Value
                        % natural hazard filter
                        NatHaz = app.DropDown_NatHazPntSrc.Value;
                        NatHaz_lvl = app.DropDown_NatHazLvlPntSrc.Value;
                        [ids_2_plot_nathaz] = filter_by_nat_haz_pop(app,PntSrc_name,NatHaz,NatHaz_lvl);
                    else
                        ids_2_plot_nathaz = true(size(app.DataTree.CheckedNodes(inds_pt_src(j)).NodeData,1),1);
                    end

                    if app.EmissionsButton.Value
                        % emissions filter
                        [ids_2_plot_emissions] = filter_by_emissions(app,PntSrc_name,str2double(app.Edit_Emissions.Value));
                    else
                        ids_2_plot_emissions = true(size(app.DataTree.CheckedNodes(inds_pt_src(j)).NodeData,1),1);
                    end

                    if app.PopulationButton.Value
                        % population filter
                        pop_lvl = str2double(app.Edit_Population.Value);
                        [ids_2_plot_pop] = filter_by_nat_haz_pop(app,PntSrc_name,'Population',pop_lvl);
                    else
                        ids_2_plot_pop = true(size(app.DataTree.CheckedNodes(inds_pt_src(j)).NodeData,1),1);
                    end


                    ids_2_plot = ids_2_plot_dist & ids_2_plot_nathaz & ids_2_plot_emissions & ids_2_plot_pop;


                    objs = get(app.ax, 'Children');
                    objs_cell = num2cell(objs);
                    obj_tag_cell = cellfun(@(x) x.Tag,objs_cell,'UniformOutput',false);
                    ids_objs_2_del   = ismember(obj_tag_cell,app.DataTree.CheckedNodes(inds_pt_src(j)).Text);
                    delete(objs(ids_objs_2_del))

                    pointLayer(app.ax, app.DataTree.CheckedNodes(inds_pt_src(j)),ids_2_plot)
                end
            end


        end

        % Button pushed function: UpdateNDVIButton
        function filter_NDVI(app, event)
            ids_2_plot_NDVI = filter_NDVI_nathaz(app,app.DropDown_NatHazNDVI.Value,app.DropDown_NatHazLvlNDVI.Value);

            objs = get(app.ax, 'Children');
            objs_cell = num2cell(objs);
            obj_tag_cell = cellfun(@(x) x.Tag,objs_cell,'UniformOutput',false);
            ids_objs_2_del   = ismember(obj_tag_cell,'NDVI');
            delete(objs(ids_objs_2_del))

            polyLayer(app.ax, app.NDVINode,ids_2_plot_NDVI);

            if ~isempty(app.DataTree.CheckedNodes)
                if ~any(ismember({app.DataTree.CheckedNodes.Tag},'NDVI'))
                    app.DataTree.CheckedNodes = [app.DataTree.CheckedNodes; app.NDVINode];
                end
            else
                app.DataTree.CheckedNodes = [app.DataTree.CheckedNodes; app.NDVINode];
            end
        end

        % Button pushed function: CheckLocationButton
        function check_location(app, event)
            % clear previous circle
            ClearCircle(app)

            mi2deg = @(x)x/69; % convert miles to degrees
            r = mi2deg(str2double(app.Edit_rad.Value));
            x = str2double(app.Edit_lon.Value);
            y = str2double(app.Edit_lat.Value);
            geolimits(app.ax, [y-2*r y+2*r], [x-2*r x+2*r]);

            % add red dot at point of interest
            geoplot(app.ax,y,x,'ro','markerfacecolor','r','markersize',10,'Tag','point_of_interest','Displayname','Point of Interest');

            % add circle at radius of interest
            th = linspace(0,2*pi);
            geoplot(app.ax,r*sin(th)+y,r*cos(th)+x,'r','linewidth',2,'Tag','radius_of_interest','Displayname','Radius of Interest');

            for k = 1:length(app.ax.Children); Tag_array{k} = app.ax.Children(k).Tag;end

            % if DAC are not already plotted, plot them
            if ~any(strcmp(Tag_array,app.DisadvantagedCommunitiesNode.Text))
                polyLayer(app.ax, app.DisadvantagedCommunitiesNode)
                app.DataTree.CheckedNodes = [app.DataTree.CheckedNodes; app.DisadvantagedCommunitiesNode];
            end

        end

        % Button pushed function: ClearButton
        function ClearCircle(app, event)
            objs = get(app.ax, 'Children');
            objs_cell = num2cell(objs);
            obj_tag_cell = cellfun(@(x) x.Tag,objs_cell,'UniformOutput',false);
            ids_objs_2_del   = ismember(obj_tag_cell,{'point_of_interest','radius_of_interest'});
            delete(objs(ids_objs_2_del))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.DataPanel = uipanel(app.GridLayout3);
            app.DataPanel.Title = 'Data Layers';
            app.DataTree = uitree(app.DataPanel, 'checkbox');
            app.DataTree.DoubleClickedFcn = createCallbackFcn(app, @DataTreeDoubleClicked, true);
            app.PointSourcesNode = uitreenode(app.DataTree);
            app.PointSourcesNode.Text = 'Point Sources';
            app.PowerPlantsNode = uitreenode(app.PointSourcesNode);
            app.PowerPlantsNode.Text = 'Power Plants';
            app.CementPlantsNode = uitreenode(app.PointSourcesNode);
            app.CementPlantsNode.Text = 'Cement Plants';
            app.EthanolPlantsNode = uitreenode(app.PointSourcesNode);
            app.EthanolPlantsNode.Text = 'Ethanol Plants';
            app.IronandSteelPlantsNode = uitreenode(app.PointSourcesNode);
            app.IronandSteelPlantsNode.Text = 'Iron and Steel Plants';
            app.CCSInfrastructureNode = uitreenode(app.DataTree);
            app.CCSInfrastructureNode.Text = 'CCS Infrastructure';
            app.PipelinesNode = uitreenode(app.CCSInfrastructureNode);
            app.PipelinesNode.Text = 'Pipelines';
            app.OperationalPipelinesNode = uitreenode(app.PipelinesNode);
            app.OperationalPipelinesNode.Text = 'Operational Pipelines';
            app.PlannedPipelinesNode = uitreenode(app.PipelinesNode);
            app.PlannedPipelinesNode.Text = 'Planned Pipelines';
            app.InjectionsitesNode = uitreenode(app.CCSInfrastructureNode);
            app.InjectionsitesNode.Text = 'Injection sites';
            app.GeologicalreservoirsNode = uitreenode(app.CCSInfrastructureNode);
            app.GeologicalreservoirsNode.Text = 'Geological reservoirs';
            app.NaturalHazardsNode = uitreenode(app.DataTree);
            app.NaturalHazardsNode.Text = 'Natural Hazards';
            app.PresentNode = uitreenode(app.NaturalHazardsNode);
            app.PresentNode.Text = 'Present';
            app.EarthquakeNode = uitreenode(app.PresentNode);
            app.EarthquakeNode.Text = 'Earthquake';
            app.DroughtNode = uitreenode(app.PresentNode);
            app.DroughtNode.Text = 'Drought';
            app.HurricaneNode = uitreenode(app.PresentNode);
            app.HurricaneNode.Text = 'Hurricane';
            app.RiverineFloodingNode = uitreenode(app.PresentNode);
            app.RiverineFloodingNode.Text = 'Riverine Flooding';
            app.StrongWindNode = uitreenode(app.PresentNode);
            app.StrongWindNode.Text = 'Strong Wind';
            app.WildfireNode = uitreenode(app.PresentNode);
            app.WildfireNode.Text = 'Wildfire';
            app.Projected2050Node = uitreenode(app.NaturalHazardsNode);
            app.Projected2050Node.Text = 'Projected ~2050';
            app.MaxSummerDailyAvgTemperatureFNode = uitreenode(app.Projected2050Node);
            app.MaxSummerDailyAvgTemperatureFNode.Text = 'Max Summer Daily Avg Temperature [F]';
            app.RCP85Node_2 = uitreenode(app.MaxSummerDailyAvgTemperatureFNode);
            app.RCP85Node_2.Text = 'RCP 8.5';
            app.ConsecutiveDayswithoutPrecipitationNode = uitreenode(app.Projected2050Node);
            app.ConsecutiveDayswithoutPrecipitationNode.Text = 'Consecutive Days without Precipitation';
            app.RCP45Node = uitreenode(app.ConsecutiveDayswithoutPrecipitationNode);
            app.RCP45Node.Text = 'RCP 4.5';
            app.RCP85Node = uitreenode(app.ConsecutiveDayswithoutPrecipitationNode);
            app.RCP85Node.Text = 'RCP 8.5';
            app.TotalAnnualPrecipitationinNode = uitreenode(app.Projected2050Node);
            app.TotalAnnualPrecipitationinNode.Text = 'Total Annual Precipitation [in]';
            app.RCP45Node_2 = uitreenode(app.TotalAnnualPrecipitationinNode);
            app.RCP45Node_2.Text = 'RCP 4.5';
            app.RCP85Node_3 = uitreenode(app.TotalAnnualPrecipitationinNode);
            app.RCP85Node_3.Text = 'RCP 8.5';
            app.GridCarbonIntensitylbsCO2MWhNode = uitreenode(app.DataTree);
            app.GridCarbonIntensitylbsCO2MWhNode.Text = 'Grid Carbon Intensity [lbs CO2/MWh]';
            app.current2021Node = uitreenode(app.GridCarbonIntensitylbsCO2MWhNode);
            app.current2021Node.Text = 'current (2021)';
            app.projected2050Node = uitreenode(app.GridCarbonIntensitylbsCO2MWhNode);
            app.projected2050Node.Text = 'projected (2050)';
            app.NDVINode = uitreenode(app.DataTree);
            app.NDVINode.Text = 'NDVI';
            app.SocialVulnerabilityNode = uitreenode(app.DataTree);
            app.SocialVulnerabilityNode.Text = 'Social Vulnerability';
            app.CommunityResilienceNode = uitreenode(app.DataTree);
            app.CommunityResilienceNode.Text = 'Community Resilience';
            app.PopulationNode = uitreenode(app.DataTree);
            app.PopulationNode.Text = 'Population';
            app.InterstatesNode = uitreenode(app.DataTree);
            app.InterstatesNode.Text = 'Interstates';
            app.DisadvantagedCommunitiesNode = uitreenode(app.DataTree);
            app.DisadvantagedCommunitiesNode.Text = 'Disadvantaged Communities';
            % Assign Checked Nodes
            app.DataTree.CheckedNodesChangedFcn = createCallbackFcn(app, @checkchange, true);
            app.PointsourceFiltersPanel = uipanel(app.GridLayout3);
            app.PointsourceFiltersPanel.Title = 'Point source Filters';
            app.GridLayout = uigridlayout(app.PointsourceFiltersPanel);
            app.DistancetoInjectionButton = uibutton(app.GridLayout, 'state');
            app.DistancetoInjectionButton.Tooltip = {'Select to filter point sources by distance to injection site'};
            app.DistancetoInjectionButton.Text = {'Distance to'; 'Injection'};
            app.NaturalHazardRiskPntSrcButton = uibutton(app.GridLayout, 'state');
            app.NaturalHazardRiskPntSrcButton.Tooltip = {'Click to filter point sources by natural hazard risk'};
            app.NaturalHazardRiskPntSrcButton.Text = {'Natural '; 'Hazard Risk'};
            app.EmissionsButton = uibutton(app.GridLayout, 'state');
            app.EmissionsButton.Tooltip = {'Click to filter point sources by emissions in Mega metric tonnes of CO2 equivalent per year'};
            app.EmissionsButton.Text = 'Emissions';
            app.PopulationButton = uibutton(app.GridLayout, 'state');
            app.PopulationButton.Tooltip = {'Click to filter point sources by population'};
            app.PopulationButton.Text = 'Population';
            app.UpdatepointsourcesButton = uibutton(app.GridLayout, 'push');
            app.UpdatepointsourcesButton.ButtonPushedFcn = createCallbackFcn(app, @filter_pt_src, true);
            app.UpdatepointsourcesButton.Tooltip = {'Click to update point sources according to selected filters'};
            app.UpdatepointsourcesButton.Text = 'Update point sources';
            app.DropDown_NatHazPntSrc = uidropdown(app.GridLayout);
            app.DropDown_NatHazPntSrc.Items = {'None', 'Earthquake', 'Drought', 'Hurricane', 'Riverine Flooding', 'Strong Wind', 'Wildfire'};
            app.DropDown_NatHazPntSrc.Value = 'None';
            app.DropDown_NatHazLvlPntSrc = uidropdown(app.GridLayout);
            app.DropDown_NatHazLvlPntSrc.Items = {'None', 'Very Low', 'Relatively Low', 'Relatively Moderate', 'Relatively High', 'Very High'};
            app.DropDown_NatHazLvlPntSrc.Value = 'None';
            app.Edit_DistInj = uieditfield(app.GridLayout, 'text');
            app.Edit_DistInj.Value = '0';
            app.Edit_Emissions = uieditfield(app.GridLayout, 'text');
            app.Edit_Emissions.Tooltip = {'Enter lower bound in Mega metric tonnes of CO2 equivalent per year'};
            app.Edit_Emissions.Value = '0';
            app.Edit_Population = uieditfield(app.GridLayout, 'text');
            app.Edit_Population.Tooltip = {'Enter county-level population '};
            app.Edit_Population.Placeholder = '0';
            app.Edit_Population.Value = '0';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '<';
            app.milesLabel = uilabel(app.GridLayout);
            app.milesLabel.Text = 'miles';
            app.MTCO_2eaLabel = uilabel(app.GridLayout);
            app.MTCO_2eaLabel.Interpreter = 'latex';
            app.MTCO_2eaLabel.Text = 'MT CO$_2$e/a';
            app.peopleLabel = uilabel(app.GridLayout);
            app.peopleLabel.Text = 'people';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '>';
            app.DropDown_PopGTLT = uidropdown(app.GridLayout);
            app.DropDown_PopGTLT.Items = {'>', '<'};
            app.DropDown_PopGTLT.Value = '>';
            app.FilterVegetationIndexPanel = uipanel(app.GridLayout3);
            app.FilterVegetationIndexPanel.Title = 'Filter Vegetation Index';
            app.GridLayout2 = uigridlayout(app.FilterVegetationIndexPanel);
            app.NaturalHazardRiskNDVIButton = uibutton(app.GridLayout2, 'state');
            app.NaturalHazardRiskNDVIButton.Tooltip = {'Click to filter county NDVI data by natural hazards'};
            app.NaturalHazardRiskNDVIButton.Text = {'Natural '; 'Hazard Risk'};
            app.DropDown_NatHazNDVI = uidropdown(app.GridLayout2);
            app.DropDown_NatHazNDVI.Items = {'None', 'Earthquake', 'Drought', 'Hurricane', 'Riverine Flooding', 'Strong Wind', 'Wildfire'};
            app.DropDown_NatHazNDVI.Value = 'None';
            app.DropDown_NatHazLvlNDVI = uidropdown(app.GridLayout2);
            app.DropDown_NatHazLvlNDVI.Items = {'None', 'Very Low', 'Relatively Low', 'Relatively Moderate', 'Relatively High', 'Very High'};
            app.DropDown_NatHazLvlNDVI.Value = 'None';
            app.UpdateNDVIButton = uibutton(app.GridLayout2, 'push');
            app.UpdateNDVIButton.ButtonPushedFcn = createCallbackFcn(app, @filter_NDVI, true);
            app.UpdateNDVIButton.Tooltip = {'Click to update county NDVI by natural hazard risk'};
            app.UpdateNDVIButton.Text = 'Update NDVI';
            app.CheckalocationforDACproximityPanel = uipanel(app.GridLayout3);
            app.CheckalocationforDACproximityPanel.Title = 'Check a location for DAC proximity';
            app.GridLayout2_2 = uigridlayout(app.CheckalocationforDACproximityPanel);
            app.Edit_lon = uieditfield(app.GridLayout2_2, 'text');
            app.Edit_lon.Tooltip = {'Enter longitude from -180 to 180 [Note degrees West is negative]'};
            app.Edit_rad = uieditfield(app.GridLayout2_2, 'text');
            app.radiusmiLabel = uilabel(app.GridLayout2_2);
            app.radiusmiLabel.Text = {'radius '; '[mi]'};
            app.lonoLabel = uilabel(app.GridLayout2_2);
            app.lonoLabel.Interpreter = 'latex';
            app.lonoLabel.Text = 'lon [$^o$]';
            app.Edit_lat = uieditfield(app.GridLayout2_2, 'text');
            app.Edit_lat.Tooltip = {'Enter degree latitude from -90 to 90'};
            app.latoLabel = uilabel(app.GridLayout2_2);
            app.latoLabel.Interpreter = 'latex';
            app.latoLabel.Text = 'lat [$^o$]';
            app.ClearButton = uibutton(app.GridLayout2_2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCircle, true);
            app.ClearButton.Text = 'Clear';
            app.CheckLocationButton = uibutton(app.GridLayout2_2, 'push');
            app.CheckLocationButton.ButtonPushedFcn = createCallbackFcn(app, @check_location, true);
            app.CheckLocationButton.Text = {'Check'; 'Location'};
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @resetMapSize, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResilienceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end